classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        InicioTab                      matlab.ui.container.Tab
        GananciasparabandasdefrecuenciasPanel  matlab.ui.container.Panel
        SubBassSliderLabel             matlab.ui.control.Label
        SubBassSlider                  matlab.ui.control.Slider
        BassSliderLabel                matlab.ui.control.Label
        BassSlider                     matlab.ui.control.Slider
        LowMidsSliderLabel             matlab.ui.control.Label
        LowMidsSlider                  matlab.ui.control.Slider
        HighMidsSliderLabel            matlab.ui.control.Label
        HighMidsSlider                 matlab.ui.control.Slider
        PresenceSliderLabel            matlab.ui.control.Label
        PresenceSlider                 matlab.ui.control.Slider
        BrillianceSliderLabel          matlab.ui.control.Label
        BrillianceSlider               matlab.ui.control.Slider
        Label                          matlab.ui.control.Label
        Knob2Label                     matlab.ui.control.Label
        Knob2                          matlab.ui.control.DiscreteKnob
        CalcularButton                 matlab.ui.control.Button
        PlayButton                     matlab.ui.control.Button
        PlayButton_2                   matlab.ui.control.Button
        AudiooriginalLabel             matlab.ui.control.Label
        AudioecualizadoLabel           matlab.ui.control.Label
        InterpolacinLabel              matlab.ui.control.Label
        DecimacinLabel                 matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        SonidooriginalTab              matlab.ui.container.Tab
        PlayButton_3                   matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        SealmuestreadaTab              matlab.ui.container.Tab
        MododelprogramaEditFieldLabel  matlab.ui.control.Label
        MododelprogramaEditField       matlab.ui.control.EditField
        FrecuenciademuestreoHzEditFieldLabel  matlab.ui.control.Label
        FrecuenciademuestreoHzEditField  matlab.ui.control.NumericEditField
        UIAxes2                        matlab.ui.control.UIAxes
        SealmuestreadaHzTab            matlab.ui.container.Tab
        UIAxes3                        matlab.ui.control.UIAxes
        BandasTab                      matlab.ui.container.Tab
        UIAxes4                        matlab.ui.control.UIAxes
        AudioecualizadoespectroTab     matlab.ui.container.Tab
        UIAxes5                        matlab.ui.control.UIAxes
        AudioecualizadotiempoTab       matlab.ui.container.Tab
        UIAxes6                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
        name_audio = "rush";
        fs;
        a_m;
        a_lpf;
        f_n;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Knob2.Items = ["-11","-7","-5","-3","-2","1","2","3","4"]
            app.Knob2.Value = "1"
            
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            cla(app.UIAxes)
            cla(app.UIAxes2)
            cla(app.UIAxes3)
            cla(app.UIAxes4)
            cla(app.UIAxes5)
            cla(app.UIAxes6)
            
            selector_d_i = app.Knob2.Value
            
            if str2num(selector_d_i) <1
                select = 1;
                app.MododelprogramaEditField.Value = "Decimación";
            elseif str2num(selector_d_i)>1
                select = 2 ;     
                app.MododelprogramaEditField.Value = "Interpolación";
            elseif str2num(selector_d_i) == 1
                select = 1;
                app.MododelprogramaEditField.Value = "Señal original";
            end
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            r = abs(str2num(selector_d_i));
            
            s_b = app.SubBassSlider.Value
            b = app.BassSlider.Value
            l_m = app.LowMidsSlider.Value
            h_m = app.HighMidsSlider.Value
            p = app.PresenceSlider.Value
            brilliance = app.BrillianceSlider.Value
            
            app.UIAxes3.XAxis.Exponent = 3;
            app.UIAxes4.XAxis.Exponent = 3;
            app.UIAxes5.XAxis.Exponent = 3;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            [a,app.fs] = audioread(app.name_audio+".wav");
            info = audioinfo(app.name_audio+".wav");
            
            d=info.Duration;
            Fs =info.SampleRate  % Sampling frequency                    
            T = 1/Fs;             % Sampling period       
            L = length(a);        % Length of signal
            t = (0:L-1)*T;
            
            app.a_m = a(:,1); % canal izquierdo
            app.a_m = app.a_m.';
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            plot(app.UIAxes,t,app.a_m);
            legend(app.UIAxes,"Audio")
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            if select == 1
                xn = decimate(app.a_m,r);
                t = linspace(0,d,ceil(length(t)/r));
                stem(app.UIAxes2,t,xn);
                title(app.UIAxes2,'decimacion');
            else
                xn = interp(app.a_m,r);  
                t=linspace(0,d,(length(t)*r));
                stem(app.UIAxes2,t,xn);
                title(app.UIAxes2,'interpolacion');
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            A_m =fftshift( fft(xn));
            f = linspace(-Fs/2,Fs/2,length(A_m));
            hold(app.UIAxes3,"on");
            plot(app.UIAxes3,f,abs(A_m)/max(abs(A_m)));
            
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            [pks,locs] = findpeaks(abs(A_m), f);
            fc = (max(locs))
            filtro=1.*((abs(f)<=fc)&((abs(f)>=0)));
            plot(app.UIAxes3,f,filtro,'r');
            legend(app.UIAxes3,"Audio","Filtro");
            hold(app.UIAxes3,"off");
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            A_lpf=A_m.*filtro;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            G0=db2mag(s_b);
            G1=db2mag(b);
            G2=db2mag(l_m);
            G3=db2mag(h_m);
            G4=db2mag(p);
            G5=db2mag(brilliance);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            filtro_Sub_Bass = G0.*((abs(f)<=60)&((abs(f)>=16)));
            plot(app.UIAxes4,f,filtro_Sub_Bass,'r','LineWidth',1.5);
            hold(app.UIAxes4, "on")
            filtro_Bass = G1.*((abs(f)<=250)&((abs(f)>60)));
            plot(app.UIAxes4,f,filtro_Bass,'g','LineWidth',1.5);
            
            filtro_Low_Mids = G2.*((abs(f)<=2000)&((abs(f)>250)));
            plot(app.UIAxes4,f,filtro_Low_Mids,'b','LineWidth',1.5);
            
            filtro_High_Mids = G3.*((abs(f)<=4000)&((abs(f)>2000)));
            plot(app.UIAxes4,f,filtro_High_Mids,'c','LineWidth',1.5);
            
            filtro_Presence = G4.*((abs(f)<=6000)&((abs(f)>4000)));
            plot(app.UIAxes4,f,filtro_Presence,'y','LineWidth',1.5);
            
            filtro_Brilliance = G5.*((abs(f)<=16000)&((abs(f)>6000)));
            plot(app.UIAxes4,f,filtro_Brilliance,'m','LineWidth',1.5);
            legend(app.UIAxes4, "Sub-bass", "Bass", "Low Mids", "High Mids","Presence", "Brilliance")
            hold(app.UIAxes4, "off")
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            A_lpf = A_lpf.*(filtro_Sub_Bass + filtro_Bass + filtro_Low_Mids + filtro_High_Mids + filtro_Presence + filtro_Brilliance);
            plot(app.UIAxes5,f,abs(A_lpf)/max(abs(A_lpf)));
            legend(app.UIAxes5, "Audio")
           
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            app.a_lpf = real(ifft(fftshift(A_lpf)));
            plot(app.UIAxes6,t,app.a_lpf);
            legend(app.UIAxes6, "Audio");
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            if select == 1  %2
                
                app.f_n = Fs/r
            elseif select == 2  %1
                app.f_n = Fs*r;
            end
            
            app.FrecuenciademuestreoHzEditField.Value = app.f_n;
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
             [a,app.fs] = audioread(app.name_audio+".wav");
            info = audioinfo(app.name_audio+".wav");
            
            d=info.Duration;
            Fs =info.SampleRate;  % Sampling frequency                    
            T = 1/Fs;             % Sampling period       
            L = length(a);        % Length of signal
            t = (0:L-1)*T;
            
            app.a_m = a(:,1); % canal izquierdo
            app.a_m = app.a_m.';
            
            sound(app.a_m, app.fs);
        end

        % Button pushed function: PlayButton_2
        function PlayButton_2Pushed(app, event)
            sound(app.a_lpf*2, app.f_n);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InicioTab = uitab(app.TabGroup);
            app.InicioTab.Title = 'Inicio';
            app.GananciasparabandasdefrecuenciasPanel = uipanel(app.InicioTab);
            app.GananciasparabandasdefrecuenciasPanel.Title = 'Ganancias para bandas de frecuencias ';
            app.SubBassSliderLabel = uilabel(app.GananciasparabandasdefrecuenciasPanel);
            app.SubBassSliderLabel.Text = 'Sub - Bass';
            app.SubBassSlider = uislider(app.GananciasparabandasdefrecuenciasPanel);
            app.SubBassSlider.Limits = [-24 24];
            app.BassSliderLabel = uilabel(app.GananciasparabandasdefrecuenciasPanel);
            app.BassSliderLabel.Text = 'Bass';
            app.BassSlider = uislider(app.GananciasparabandasdefrecuenciasPanel);
            app.BassSlider.Limits = [-24 24];
            app.LowMidsSliderLabel = uilabel(app.GananciasparabandasdefrecuenciasPanel);
            app.LowMidsSliderLabel.Text = 'Low -Mids';
            app.LowMidsSlider = uislider(app.GananciasparabandasdefrecuenciasPanel);
            app.LowMidsSlider.Limits = [-24 24];
            app.HighMidsSliderLabel = uilabel(app.GananciasparabandasdefrecuenciasPanel);
            app.HighMidsSliderLabel.Text = 'High - Mids';
            app.HighMidsSlider = uislider(app.GananciasparabandasdefrecuenciasPanel);
            app.HighMidsSlider.Limits = [-24 24];
            app.PresenceSliderLabel = uilabel(app.GananciasparabandasdefrecuenciasPanel);
            app.PresenceSliderLabel.Text = 'Presence';
            app.PresenceSlider = uislider(app.GananciasparabandasdefrecuenciasPanel);
            app.PresenceSlider.Limits = [-24 24];
            app.BrillianceSliderLabel = uilabel(app.GananciasparabandasdefrecuenciasPanel);
            app.BrillianceSliderLabel.Text = 'Brilliance';
            app.BrillianceSlider = uislider(app.GananciasparabandasdefrecuenciasPanel);
            app.BrillianceSlider.Limits = [-24 24];
            app.Label = uilabel(app.InicioTab);
            app.Label.Text = {'Conversor de frecuencia de muestreo y ecualizador para  '; 'señales de audio en tiempo discreto.'};
            app.Knob2Label = uilabel(app.InicioTab);
            app.Knob2Label.Text = 'Knob2';
            app.Knob2 = uiknob(app.InicioTab, 'discrete');
            app.CalcularButton = uibutton(app.InicioTab, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.PlayButton = uibutton(app.InicioTab, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play ';
            app.PlayButton_2 = uibutton(app.InicioTab, 'push');
            app.PlayButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlayButton_2Pushed, true);
            app.PlayButton_2.Text = 'Play ';
            app.AudiooriginalLabel = uilabel(app.InicioTab);
            app.AudiooriginalLabel.Text = 'Audio original';
            app.AudioecualizadoLabel = uilabel(app.InicioTab);
            app.AudioecualizadoLabel.Text = 'Audio ecualizado';
            app.InterpolacinLabel = uilabel(app.InicioTab);
            app.InterpolacinLabel.Text = 'Interpolación';
            app.DecimacinLabel = uilabel(app.InicioTab);
            app.DecimacinLabel.Text = 'Decimación';
            app.Image = uiimage(app.InicioTab);
            app.Image.ImageSource = 'logo-dark-ucuenca.jpg';
            app.SonidooriginalTab = uitab(app.TabGroup);
            app.SonidooriginalTab.Title = 'Sonido original';
            app.PlayButton_3 = uibutton(app.SonidooriginalTab, 'push');
            app.PlayButton_3.Text = 'Play ';
            app.UIAxes = uiaxes(app.SonidooriginalTab);
            title(app.UIAxes, 'Sonido original')
            xlabel(app.UIAxes, 'Tiempo [s]')
            ylabel(app.UIAxes, 'Amplitud')
            zlabel(app.UIAxes, 'Z')
            app.SealmuestreadaTab = uitab(app.TabGroup);
            app.SealmuestreadaTab.Title = 'Señal muestreada';
            app.MododelprogramaEditFieldLabel = uilabel(app.SealmuestreadaTab);
            app.MododelprogramaEditFieldLabel.Text = 'Modo del programa';
            app.MododelprogramaEditField = uieditfield(app.SealmuestreadaTab, 'text');
            app.MododelprogramaEditField.Editable = 'off';
            app.FrecuenciademuestreoHzEditFieldLabel = uilabel(app.SealmuestreadaTab);
            app.FrecuenciademuestreoHzEditFieldLabel.Text = 'Frecuencia de muestreo [Hz]';
            app.FrecuenciademuestreoHzEditField = uieditfield(app.SealmuestreadaTab, 'numeric');
            app.FrecuenciademuestreoHzEditField.Editable = 'off';
            app.UIAxes2 = uiaxes(app.SealmuestreadaTab);
            title(app.UIAxes2, 'Señal muestreada')
            xlabel(app.UIAxes2, 'Tiempo [s]')
            ylabel(app.UIAxes2, 'Amplitud')
            zlabel(app.UIAxes2, 'Z')
            app.SealmuestreadaHzTab = uitab(app.TabGroup);
            app.SealmuestreadaHzTab.Title = 'Señal muestreada [Hz]';
            app.UIAxes3 = uiaxes(app.SealmuestreadaHzTab);
            title(app.UIAxes3, 'Espectro en frecuencia del audio muestreado')
            xlabel(app.UIAxes3, 'Hz')
            ylabel(app.UIAxes3, 'Amplitud')
            zlabel(app.UIAxes3, 'Z')
            app.BandasTab = uitab(app.TabGroup);
            app.BandasTab.Title = 'Bandas';
            app.UIAxes4 = uiaxes(app.BandasTab);
            title(app.UIAxes4, 'Bandas del ecualizador')
            xlabel(app.UIAxes4, 'Hz')
            ylabel(app.UIAxes4, 'Amplitud')
            zlabel(app.UIAxes4, 'Z')
            app.AudioecualizadoespectroTab = uitab(app.TabGroup);
            app.AudioecualizadoespectroTab.Title = 'Audio ecualizado [espectro]';
            app.UIAxes5 = uiaxes(app.AudioecualizadoespectroTab);
            title(app.UIAxes5, 'Espectro en frecuencia del audio ecualizado')
            xlabel(app.UIAxes5, 'Hz')
            ylabel(app.UIAxes5, 'Amplitud')
            zlabel(app.UIAxes5, 'Z')
            app.AudioecualizadotiempoTab = uitab(app.TabGroup);
            app.AudioecualizadotiempoTab.Title = 'Audio ecualizado [ tiempo]';
            app.UIAxes6 = uiaxes(app.AudioecualizadotiempoTab);
            title(app.UIAxes6, 'Audio ecualizado en el dominio del tiempo')
            xlabel(app.UIAxes6, 'tiempo [s]')
            ylabel(app.UIAxes6, 'Amplitud')
            zlabel(app.UIAxes6, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end